classdef Poject_class0511 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        shutdownPanel             matlab.ui.container.Panel
        Image2                    matlab.ui.control.Image
        SettingPanel              matlab.ui.container.Panel
        shutdown                  matlab.ui.control.Image
        ChangeBGDropDown          matlab.ui.control.DropDown
        Label_4                   matlab.ui.control.Label
        AnimationPanel            matlab.ui.container.Panel
        CloseAnimPanel            matlab.ui.control.Image
        Label_AnimState           matlab.ui.control.Label
        AnimStopBtn               matlab.ui.control.Button
        AnimExecuteBtn            matlab.ui.control.Button
        AnimDropDown              matlab.ui.control.DropDown
        Label_3                   matlab.ui.control.Label
        UIAxesAnim                matlab.ui.control.UIAxes
        DrawingPanel              matlab.ui.container.Panel
        CloseDrawingPanel         matlab.ui.control.Image
        StartButton               matlab.ui.control.Button
        TabGroup                  matlab.ui.container.TabGroup
        DTab                      matlab.ui.container.Tab
        usedefaultstyleCheckBox   matlab.ui.control.CheckBox
        LineStyleButtonGroup      matlab.ui.container.ButtonGroup
        Button_4                  matlab.ui.control.RadioButton
        Button_3                  matlab.ui.control.RadioButton
        Button_2                  matlab.ui.control.RadioButton
        ColorThemeButtonGroup     matlab.ui.container.ButtonGroup
        blueButton                matlab.ui.control.RadioButton
        greenButton               matlab.ui.control.RadioButton
        redButton                 matlab.ui.control.RadioButton
        ListListBox               matlab.ui.control.ListBox
        ListListBoxLabel          matlab.ui.control.Label
        DTab_2                    matlab.ui.container.Tab
        ColormapButtonGroup       matlab.ui.container.ButtonGroup
        summerButton_2            matlab.ui.control.RadioButton
        coolButton                matlab.ui.control.RadioButton
        hotButton                 matlab.ui.control.RadioButton
        ListListBox_2             matlab.ui.control.ListBox
        ListListBox_2Label        matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
        CalculatePanel            matlab.ui.container.Panel
        CloseCalculatePanel       matlab.ui.control.Image
        bottleCat                 matlab.ui.control.Image
        thisismyFRIENDLabel       matlab.ui.control.Label
        appleCat                  matlab.ui.control.Image
        PolyoutputEditField       matlab.ui.control.EditField
        outputEditField_2Label    matlab.ui.control.Label
        PolyButton                matlab.ui.control.Button
        polynomialEditField       matlab.ui.control.EditField
        polynomialEditFieldLabel  matlab.ui.control.Label
        youselectLabel            matlab.ui.control.Label
        operatorDropDown          matlab.ui.control.DropDown
        operatorDropDownLabel     matlab.ui.control.Label
        outputEditField           matlab.ui.control.EditField
        outputEditFieldLabel      matlab.ui.control.Label
        ClearButton               matlab.ui.control.Button
        Button_5                  matlab.ui.control.Button
        inputBEditField           matlab.ui.control.EditField
        inputAEditField           matlab.ui.control.EditField
        Label_2                   matlab.ui.control.Label
        SquareButton              matlab.ui.control.Button
        sqrtButton                matlab.ui.control.Button
        sortButton                matlab.ui.control.Button
        maxButton                 matlab.ui.control.Button
        HomePanel                 matlab.ui.container.Panel
        Image_background          matlab.ui.control.Image
        stateBar                  matlab.ui.container.Panel
        Label__date               matlab.ui.control.Label
        Image_home                matlab.ui.control.Image
        DrawingButton             matlab.ui.control.Button
        AnimationButton           matlab.ui.control.Button
        CalculateButton           matlab.ui.control.Button
        Image                     matlab.ui.control.Image
        Label__time               matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function CalculatePanelBoom(app,elementIndex,isVisible,time)
            switch elementIndex 
                case 0
                    pause(time);
                case 1
                    pause(time);
                    app.polynomialEditField.Visible = isVisible;
                    app.appleCat.Position = app.polynomialEditField.Position;
                case 2
                    pause(time);
                    app.PolyoutputEditField.Visible = isVisible;   
                    app.appleCat.Position = app.PolyoutputEditField.Position;
                case 3
                    pause(time);
                    app.PolyButton.Visible = isVisible;  
                    app.appleCat.Position = app.PolyButton.Position;
                case 4
                    pause(time);
                    app.youselectLabel.Visible = isVisible;   
                    app.appleCat.Position = app.youselectLabel.Position;
                case 5
                    pause(time);
                    app.operatorDropDown.Visible = isVisible;   
                    app.appleCat.Position = app.operatorDropDown.Position;
                case 6
                    pause(time);
                    app.outputEditField.Visible = isVisible;  
                    app.appleCat.Position = app.outputEditField.Position;
                case 7
                    pause(time);
                    app.ClearButton.Visible = isVisible;  
                    app.appleCat.Position = app.ClearButton.Position;
                case 8
                    pause(time);
                    app.Button_5.Visible = isVisible;   
                    app.appleCat.Position = app.Button_5.Position;
                case 9
                    pause(time);
                    app.inputAEditField.Visible = isVisible;   
                    app.appleCat.Position = app.inputAEditField.Position;
                case 10
                    pause(time);
                    app.inputBEditField.Visible = isVisible;  
                    app.appleCat.Position = app.inputBEditField.Position;
                case 11
                    pause(time);
                    app.Label_2.Visible = isVisible;   
                    app.appleCat.Position = app.Label_2.Position;
                case 12
                    pause(time);
                    app.SquareButton.Visible = isVisible;   
                    app.appleCat.Position = app.SquareButton.Position;
                case 13
                    pause(time);
                    app.sqrtButton.Visible = isVisible;   
                    app.appleCat.Position = app.sqrtButton.Position;
                case 14
                    pause(time);
                    app.sortButton.Visible = isVisible;   
                    app.appleCat.Position = app.sortButton.Position;
                case 15
                    pause(time);
                    app.maxButton.Visible = isVisible;
                    app.appleCat.Position = app.maxButton.Position;
                case 16
                    % appleCat Move
                    app.appleCat.Position = [293,101,329,291];
                    for v = 0:1:10
                       app.appleCat.Position = [200,100,350,350];
                       pause(0.2);
                       app.appleCat.Position = [250,130,350,350];
                       pause(0.2);
                       app.appleCat.Position = [400,100,350,350];
                    end

                    % Friend Move
                    app.thisismyFRIENDLabel.Visible = true;
                    app.thisismyFRIENDLabel.Position = [440, 285,222,32];
                    for i = 0:1:100
                        pause(0.06);
                        app.thisismyFRIENDLabel.Position = [440 - i*2,285,222,32];
                    end
                    
                    % bottleCat Move
                    app.bottleCat.Visible = true;
                    app.bottleCat.Position = [257,0 ,200,200];
                    for i = 0:1:100
                        pause(0.1);
                         app.bottleCat.Position = [257,0 + i* 1.1,200,200];
                    end
                   
                    
            end
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 禁用視窗縮放
            app.UIFigure.Resize = 'off';
            % app.UIFigure.WindowState = "fullscreen";
            global initClickFlag;
            initClickFlag = 0;

            % Get Time
            app.Label__date.Text = datestr(now,26);% 26 是日期 (yyyy/mm/dd)

            % >>> Timer (count Time) --------------------------------------
            % Period (為 timer 延遲秒數)
            t = timer('ExecutionMode', 'fixedRate', 'Period', 1, 'TimerFcn', @(~,~)updateTime());
            % start timer
            start(t);
            % timer callbackfunc
            function updateTime()
                % update time_text
                app.Label__time.Text = datestr(now,13);% 13 是時間 (HH:MM:SS)
            end


            app.UIFigure.Position = [100,100,1000,600];
            app.shutdownPanel.Position = [0,0,1000,600];
            app.SettingPanel.Visible = false;
            app.shutdownPanel.Visible = false;

            % 已不須操作按鈕位置
            
            app.CalculatePanel.Position = [0,47,1000,554];
            app.DrawingPanel.Position = [0,47,1000,554];
            app.AnimationPanel.Position = [0,47,1000,554];

            app.CalculateButton.BackgroundColor = [0.80,0.80,0.80];
            app.AnimationButton.BackgroundColor = [0.80,0.80,0.80];
            app.DrawingButton.BackgroundColor = [0.80,0.80,0.80];

            app.appleCat.Position = [675,282,100,100];
            app.bottleCat.Position = [257,-88,100,100];
            app.bottleCat.Visible = false;
            app.thisismyFRIENDLabel.Visible = false;

            app.CalculatePanel.Visible = false;
            app.DrawingPanel.Visible = false;
            app.AnimationPanel.Visible = false;

            global CalculatePanelElement;
            for elementIndex = 1:1:CalculatePanelElement - 1
               % disp(v) 1 ~ 16
               % disp(elements{v});
               CalculatePanelBoom(app,elementIndex,true,0);
               app.appleCat.Position = [675,282,100,100];
            end
        end

        % Value changed function: ListListBox
        function ListListBoxValueChanged(app, event)
           
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            cla(app.UIAxes);
            reset(app.UIAxes);
            TabTitle = app.TabGroup.SelectedTab.Title;
            LineFunc2DValue = app.ListListBox.Value;
            
            ColorTheme_btn = app.ColorThemeButtonGroup.SelectedObject;
            LineStyle_btn = app.LineStyleButtonGroup.SelectedObject;

            IsUseDefault = app.usedefaultstyleCheckBox.Value;
            switch TabTitle

                % 2D 區域
                % -----------------------------------------------------------
                case '2D' 
                    % 線段函數
                    switch LineFunc2DValue
                        case 'Item 1'
                            x = 0:0.1:8 * pi;
                            y = sin(x).*exp(-x/5);     
                            h = plot(app.UIAxes,y);
                        case 'Item 2'
                            x = linspace(0, 2*pi); % 在 0 到 2*pi 間，等分取 100 個點
                            y = sin(x);
                            h = plot(app.UIAxes,y);
                        case 'Item 3'
                            x = linspace(0, 10*pi); % 在 0 到 2*pi 間，等分取 100 個點
                            y = cos(x);
                            h = plot(app.UIAxes,y);
                        case 'Item 4'
                            x = peaks;
                            y = x';
                            h = plot(app.UIAxes,x,y);
                    end
                    
                    % 不使用默認樣式
                    if IsUseDefault == 0 
                        % 顏色    
                        set(h,"Color",ColorTheme_btn.Text);
            
                        % 線段樣式
                        switch LineStyle_btn.Text
                            case '實線'
                               set(h,"linestyle","-")
                            case '虛線'
                               set(h,"linestyle","--")
                            case '點線'
                               set(h,"linestyle",":")
                        end
                    end

                % 3D 區域
                % ----------------------------------------------------
                case '3D'
                    global g;
                    LineFunc3DValue = app.ListListBox_2.Value;

                    switch LineFunc3DValue

                        case '1'
                            x = linspace(-2, 2, 25); % 在 x 軸 [-2,2] 之間取 25 點
                            y = linspace(-2, 2, 25); % 在 y 軸 [-2,2] 之間取 25 點
                            [xx,yy] = meshgrid(x, y); % xx 和 yy 都是 25×25 的矩陣
                            zz = xx.*exp(-xx.^2-yy.^2); % zz 也是 25×2 的矩陣
                            g = surf(app.UIAxes,xx, yy, zz); % 畫出立體曲面圖

                        case '2'
                            x = linspace(-2, 2, 15); % 在 x 軸 [-2,2] 之間取 25 點
                            y = linspace(-2, 2, 15); % 在 y 軸 [-2,2] 之間取 25 點
                            [xx,yy] = meshgrid(x, y); % xx 和 yy 都是 25×25 的矩陣
                            zz = xx.*exp(cos(xx).^2-yy.^4); % zz 也是 25×2 的矩陣
                            g = surf(app.UIAxes,xx, yy, zz); % 畫出立體曲面圖
                        case '3'
                           x = [2 3 5 4];
                            explode = [1 1 0 0];
                            label = {'春耕', '夏耘', '秋收', '冬藏'};
                            pie3(app.UIAxes,x, explode, label);
                        case '4'
                            t = linspace(0, 2*pi, 61); % 角度的格子點
                            r = 0:0.05:1; % 長度的格子點
                            [tt, rr] = meshgrid(t, r); % 產生二維的格子點
                            [xx, yy] = pol2cart(tt, rr); % 將極座標轉換至直角座標
                            zz = xx + sqrt(-1)*yy; % 複數表示
                            ff = abs(zz.^3-1); % 曲面的函數值
                            g = polar(app.UIAxes,[0 2*pi], [0 1]); % 產生在極座標上的一條直線
                            delete(g); % 移除上述圖形，但留下極座標圖軸
                            hold(app.UIAxes,'on');
                            g = contour(app.UIAxes,xx, yy, ff, 20); % 等高線
                            g = surf(app.UIAxes,xx, yy, ff); % 曲面圖
                            hold(app.UIAxes,'off');
                            view(app.UIAxes,-19, 22); % 設定觀測角度
                    end
                    
            end
        end

        % Selection changed function: ColorThemeButtonGroup
        function ColorThemeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ColorThemeButtonGroup.SelectedObject;
        end

        % Size changed function: DTab
        function DTabSizeChanged(app, event)

        end

        % Value changed function: ListListBox_2
        function ListListBox_2ValueChanged(app, event)
            value = app.ListListBox_2.Value;
        end

        % Selection changed function: ColormapButtonGroup
        function ColormapButtonGroupSelectionChanged(app, event)
            selectedButton = app.ColormapButtonGroup.SelectedObject;
            global g;
            selectedButtonMap = selectedButton.Text;
            colormap(app.UIAxes,selectedButtonMap);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            % 已不須操作按鈕位置

            app.CalculateButton.BackgroundColor = [0.00,0.45,0.74];
            app.AnimationButton.BackgroundColor = [0.80,0.80,0.80];
            app.DrawingButton.BackgroundColor = [0.80,0.80,0.80];

            app.CalculatePanel.Visible = true;
            app.DrawingPanel.Visible = false;
            app.AnimationPanel.Visible = false;
        end

        % Button pushed function: DrawingButton
        function DrawingButtonPushed(app, event)
            % 已不須操作按鈕位置

            app.CalculateButton.BackgroundColor = [0.80,0.80,0.80];
            app.AnimationButton.BackgroundColor = [0.80,0.80,0.80];
            app.DrawingButton.BackgroundColor = [0.00,0.45,0.74];

            app.CalculatePanel.Visible = false;
            app.DrawingPanel.Visible = true;
            app.AnimationPanel.Visible = false;
        end

        % Button pushed function: sortButton
        function sortButtonPushed(app, event)
            global  operator;
            global  result;
            app.Label_2.Text = 'sort';
            app.outputEditField.Value =  num2str(sort(result));
        end

        % Button pushed function: maxButton
        function maxButtonPushed(app, event)
            global  operator;
            global  result;
            app.Label_2.Text = 'max';
            app.outputEditField.Value =  num2str(max(result));
        end

        % Button pushed function: sqrtButton
        function sqrtButtonPushed(app, event)
            global  operator;
            global  result;
            app.Label_2.Text = 'sqrt';
            app.outputEditField.Value =  num2str(sqrt(result));
        end

        % Button pushed function: SquareButton
        function SquareButtonPushed(app, event)
            global  operator;
            global  result;
            app.Label_2.Text = 'Square';
            app.outputEditField.Value =  num2str(result.^2);
        end

        % Callback function
        function HomeButtonPushed(app, event)
        % 功能已轉移至 Image_home
        end

        % Value changed function: operatorDropDown
        function operatorDropDownValueChanged(app, event)

        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            inputA = str2num(app.inputAEditField.Value);
            inputB = str2num(app.inputBEditField.Value);
            global  operator;
            global  result;
            operator = app.operatorDropDown.Value;

            switch operator
                case '+'
                    result = inputA + inputB;
                    app.outputEditField.Value =  num2str(result);
                case '-'
                    result = inputA - inputB;
                    app.outputEditField.Value =  num2str(result);
                case '*'
                    result = inputA .* inputB;
                    app.outputEditField.Value =  num2str(result);
                case '/'
                    result = inputA ./ inputB;
                    app.outputEditField.Value =  num2str(result);
                case ':)'
                    app.outputEditField.Value = ':DDDDDDDD';

                    global CalculatePanelElement;
                    CalculatePanelElement = 16;

                    randList = randperm(15);
                    disp(randList);

                    % >> element disappear Anim Action -------------------
                    for elementIndex = 1:1:CalculatePanelElement -1
                       % disp(v) 1 ~ 16
                       % disp(elements{v});
                       CalculatePanelBoom(app,randList(elementIndex),false,elementIndex / 15);
                    end

                    % >> CAT Anim Action :D ------------------------------
                    CalculatePanelBoom(app,16,false,elementIndex / 15);
                        % disp(CalculatePanelElement);
            end 


        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            global  result;
            result = 0;
            app.inputAEditField.Value = '';
            app.inputBEditField.Value = '';
            app.outputEditField.Value = '';
            app.Label_2.Text = '';
        end

        % Value changed function: usedefaultstyleCheckBox
        function usedefaultstyleCheckBoxValueChanged(app, event)
            isCheck = app.usedefaultstyleCheckBox.Value;
            if isCheck == 0
                app.ColorThemeButtonGroup.Visible = true;
                app.LineStyleButtonGroup.Visible = true;
            end
            if isCheck == 1
                app.ColorThemeButtonGroup.Visible = false;
                app.LineStyleButtonGroup.Visible = false;
            end
        end

        % Button pushed function: AnimExecuteBtn
        function AnimExecuteBtnButtonPushed(app, event)
            AnimSelect = app.AnimDropDown.Value;
            app.AnimExecuteBtn.Enable = false;
            app.AnimStopBtn.Enable = true;
            
            % TODO StopAnim Cant use 
            global StopAnim;
            StopAnim = 0;
            
            switch AnimSelect
                case 'Option 1'
                    x = 0:0.1:8*pi;
                    UIAxesAnim = plot(app.UIAxesAnim,x, sin(x).*exp(-x/5), 'EraseMode', 'xor');
                    axis(app.UIAxesAnim,[-inf inf -1 1]); % 設定圖軸的範圍
                    grid(app.UIAxesAnim,"off") % 畫出格線
                    for i = 1:5000
                        app.Label_AnimState.Text = '目前狀態 : 動畫播放中...';
                        disp(StopAnim);
                        y = sin(x-i/50).*exp(-x/5);
                        set(UIAxesAnim, 'ydata', y); % 設定新的 y 座標
                        drawnow % 立即作圖
                        if StopAnim == 1
                            StopAnim = 0;
                            break;
                        end
                    end
                
                case 'Option 2'
                    clear M % 清除電影資料矩陣 M
                    r=linspace(0, 4, 30); % 圓盤的半徑
                    t=linspace(0, 2*pi, 50); % 圓盤的極座標角度
                    [rr, tt]=meshgrid(r, t);
                    xx=rr.*cos(tt); % 產生圓盤上的 x 座標
                    yy=rr.*sin(tt); % 產生圓盤上的 y 座標
                    zz=peaks(xx,yy); % 產生 peaks 在極座標的資料
                    n = 30; % 抓取 30 個畫面
                    scale = cos(linspace(0, 2*pi, n));
                    
                    % 禁用按鈕 --------------------------------------------
                    app.Label_AnimState.Text = '目前狀態 : 抓取畫面中...';
                    app.AnimExecuteBtn.Enable = false;
                    app.AnimStopBtn.Enable = false;
                    % ----------------------------------------------------
                    
                    for i = 1:n
                        surf(app.UIAxesAnim,xx, yy, zz*scale(i)); % 畫圖
                        axis(app.UIAxesAnim,[-inf inf -inf inf -8.5 8.5]); % 固定圖軸的範圍
                        box(app.UIAxesAnim,'on')
                        M(i) = getframe(app.UIAxesAnim); % 抓取畫面，並存入電影資料矩陣 M
                    end

                    % 啟用按鈕 --------------------------------------------
                    app.Label_AnimState.Text = '目前狀態 : 播放電影中...';
                    app.AnimExecuteBtn.Enable = true;
                    app.AnimStopBtn.Enable = true;
                    % ----------------------------------------------------

                    for i = 0:10
                        movie(app.UIAxesAnim,M, 1); % 播放電影
                        if StopAnim == 1
                           StopAnim = 0;
                            break;
                        end
                    end

            end 
        end

        % Button pushed function: AnimationButton
        function AnimationButtonPushed(app, event)
            % 已不須操作按鈕位置

            app.CalculateButton.BackgroundColor = [0.80,0.80,0.80];
            app.AnimationButton.BackgroundColor = [0.00,0.45,0.74];
            app.DrawingButton.BackgroundColor = [0.80,0.80,0.80];

            app.AnimationPanel.Visible = true;
            app.CalculatePanel.Visible = false;
            app.DrawingPanel.Visible = false;
        end

        % Button pushed function: AnimStopBtn
        function AnimStopBtnPushed(app, event)
            global StopAnim;
            StopAnim = 1;
            cla(app.UIAxesAnim);
            reset(app.UIAxesAnim);
            app.Label_AnimState.Text = '目前狀態 : 圖層已清空...';
            app.AnimExecuteBtn.Enable = true;
            app.AnimStopBtn.Enable = false;
        end

        % Button pushed function: PolyButton
        function PolyButtonPushed(app, event)
            inputPoly = str2num(app.polynomialEditField.Value);
            app.PolyoutputEditField.Value = num2str(inputPoly);
        end

        % Image clicked function: Image_home
        function Image_homeClicked(app, event)
            global initClickFlag;
            initClickFlag = 0;

            % 已不須操作按鈕位置

            app.CalculateButton.BackgroundColor = [0.80,0.80,0.80];
            app.AnimationButton.BackgroundColor = [0.80,0.80,0.80];
            app.DrawingButton.BackgroundColor = [0.80,0.80,0.80];

            app.CalculatePanel.Visible = false;
            app.AnimationPanel.Visible = false;
            app.DrawingPanel.Visible = false;

            app.SettingPanel.Visible = false;
            app.bottleCat.Visible = false;
            app.thisismyFRIENDLabel.Visible = false;

            global CalculatePanelElement;
            for elementIndex = 1:1:CalculatePanelElement -1 
               % disp(v) 1 ~ 16
               % disp(elements{v});
               CalculatePanelBoom(app,elementIndex,true,0);
            end
            app.appleCat.Position = [675,282,100,100];
        end

        % Image clicked function: CloseAnimPanel
        function CloseAnimPanelImageClicked(app, event)
            app.AnimationButton.BackgroundColor = [0.80,0.80,0.80];
            app.AnimationPanel.Visible = false;
        end

        % Image clicked function: CloseDrawingPanel
        function CloseDrawingPanelImageClicked(app, event)
            app.DrawingButton.BackgroundColor = [0.80,0.80,0.80];
            app.DrawingPanel.Visible = false;
        end

        % Image clicked function: CloseCalculatePanel
        function CloseCalculatePanelImageClicked(app, event)
            app.CalculateButton.BackgroundColor = [0.80,0.80,0.80];
            app.CalculatePanel.Visible = false;
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            if app.SettingPanel.Visible == false
                app.SettingPanel.Visible = true;
            else
                app.SettingPanel.Visible = false;
            end

        end

        % Value changed function: ChangeBGDropDown
        function ChangeBGDropDownValueChanged(app, event)
            value = app.ChangeBGDropDown.Value;
            switch value
                case '默認'
                    app.Image_background.ImageSource = './homeBg1.jpg'
                case '風景'
                    app.Image_background.ImageSource = './homeBg2.jpg'
                case '純色'
                    app.Image_background.ImageSource = './homeBg3.jpg'
                case '極光'
                    app.Image_background.ImageSource = './homeBg4.jpg'
            end
        end

        % Image clicked function: shutdown
        function shutdownClicked(app, event)
            answer = questdlg('你確定要關機嗎?', ...
	                            '關機提示', ...
	                            'yes','no','no');
            % Handle response
            switch answer
                case 'yes'
                    app.SettingPanel.Visible = false;
                    app.shutdownPanel.Visible = true;
                    pause(4.5);
                    close(app.UIFigure);
                case 'no'
                    disp('取消');
                    app.SettingPanel.Visible = false;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HomePanel = uipanel(app.UIFigure);
            app.stateBar = uipanel(app.HomePanel);
            app.Label__time = uilabel(app.stateBar);
            app.Label__time.Text = '0:0:0';
            app.Image = uiimage(app.stateBar);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'win10.png');
            app.CalculateButton = uibutton(app.stateBar, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = '🧮';
            app.AnimationButton = uibutton(app.stateBar, 'push');
            app.AnimationButton.ButtonPushedFcn = createCallbackFcn(app, @AnimationButtonPushed, true);
            app.AnimationButton.Text = '🎥';
            app.DrawingButton = uibutton(app.stateBar, 'push');
            app.DrawingButton.ButtonPushedFcn = createCallbackFcn(app, @DrawingButtonPushed, true);
            app.DrawingButton.Text = '📈';
            app.Image_home = uiimage(app.stateBar);
            app.Image_home.ImageClickedFcn = createCallbackFcn(app, @Image_homeClicked, true);
            app.Image_home.ImageSource = fullfile(pathToMLAPP, 'home.png');
            app.Label__date = uilabel(app.stateBar);
            app.Label__date.Text = 'yyyy/mm/dd';
            app.Image_background = uiimage(app.HomePanel);
            app.Image_background.ImageSource = fullfile(pathToMLAPP, 'homeBg1.jpg');
            app.CalculatePanel = uipanel(app.UIFigure);
            app.CalculatePanel.Title = 'Calculate';
            app.maxButton = uibutton(app.CalculatePanel, 'push');
            app.maxButton.ButtonPushedFcn = createCallbackFcn(app, @maxButtonPushed, true);
            app.maxButton.Text = 'max';
            app.sortButton = uibutton(app.CalculatePanel, 'push');
            app.sortButton.ButtonPushedFcn = createCallbackFcn(app, @sortButtonPushed, true);
            app.sortButton.Text = 'sort';
            app.sqrtButton = uibutton(app.CalculatePanel, 'push');
            app.sqrtButton.ButtonPushedFcn = createCallbackFcn(app, @sqrtButtonPushed, true);
            app.sqrtButton.Text = 'sqrt';
            app.SquareButton = uibutton(app.CalculatePanel, 'push');
            app.SquareButton.ButtonPushedFcn = createCallbackFcn(app, @SquareButtonPushed, true);
            app.SquareButton.Text = 'Square';
            app.Label_2 = uilabel(app.CalculatePanel);
            app.Label_2.Text = '';
            app.inputAEditField = uieditfield(app.CalculatePanel, 'text');
            app.inputBEditField = uieditfield(app.CalculatePanel, 'text');
            app.Button_5 = uibutton(app.CalculatePanel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '計算';
            app.ClearButton = uibutton(app.CalculatePanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.outputEditFieldLabel = uilabel(app.CalculatePanel);
            app.outputEditFieldLabel.Text = 'output';
            app.outputEditField = uieditfield(app.CalculatePanel, 'text');
            app.operatorDropDownLabel = uilabel(app.CalculatePanel);
            app.operatorDropDownLabel.Text = 'operator';
            app.operatorDropDown = uidropdown(app.CalculatePanel);
            app.operatorDropDown.Items = {'+', '-', '*', '/', ':)'};
            app.operatorDropDown.ValueChangedFcn = createCallbackFcn(app, @operatorDropDownValueChanged, true);
            app.operatorDropDown.Value = '+';
            app.youselectLabel = uilabel(app.CalculatePanel);
            app.youselectLabel.Text = 'you select:';
            app.polynomialEditFieldLabel = uilabel(app.CalculatePanel);
            app.polynomialEditFieldLabel.Text = 'polynomial';
            app.polynomialEditField = uieditfield(app.CalculatePanel, 'text');
            app.PolyButton = uibutton(app.CalculatePanel, 'push');
            app.PolyButton.ButtonPushedFcn = createCallbackFcn(app, @PolyButtonPushed, true);
            app.PolyButton.Text = '計算';
            app.outputEditField_2Label = uilabel(app.CalculatePanel);
            app.outputEditField_2Label.Text = 'output';
            app.PolyoutputEditField = uieditfield(app.CalculatePanel, 'text');
            app.appleCat = uiimage(app.CalculatePanel);
            app.appleCat.ImageSource = fullfile(pathToMLAPP, 'pop-cat.gif');
            app.thisismyFRIENDLabel = uilabel(app.CalculatePanel);
            app.thisismyFRIENDLabel.Text = 'this is my FRIEND !!';
            app.bottleCat = uiimage(app.CalculatePanel);
            app.bottleCat.ImageSource = fullfile(pathToMLAPP, 'bottleCat.png');
            app.CloseCalculatePanel = uiimage(app.CalculatePanel);
            app.CloseCalculatePanel.ImageClickedFcn = createCallbackFcn(app, @CloseCalculatePanelImageClicked, true);
            app.CloseCalculatePanel.ImageSource = fullfile(pathToMLAPP, 'close.png');
            app.DrawingPanel = uipanel(app.UIFigure);
            app.DrawingPanel.Title = ' Drawing';
            app.UIAxes = uiaxes(app.DrawingPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TabGroup = uitabgroup(app.DrawingPanel);
            app.DTab = uitab(app.TabGroup);
            app.DTab.SizeChangedFcn = createCallbackFcn(app, @DTabSizeChanged, true);
            app.DTab.Title = '2D';
            app.ListListBoxLabel = uilabel(app.DTab);
            app.ListListBoxLabel.Text = 'List';
            app.ListListBox = uilistbox(app.DTab);
            app.ListListBox.ValueChangedFcn = createCallbackFcn(app, @ListListBoxValueChanged, true);
            app.ColorThemeButtonGroup = uibuttongroup(app.DTab);
            app.ColorThemeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColorThemeButtonGroupSelectionChanged, true);
            app.ColorThemeButtonGroup.Title = 'Color Theme';
            app.redButton = uiradiobutton(app.ColorThemeButtonGroup);
            app.redButton.Text = 'red';
            app.redButton.Value = true;
            app.greenButton = uiradiobutton(app.ColorThemeButtonGroup);
            app.greenButton.Text = 'green';
            app.blueButton = uiradiobutton(app.ColorThemeButtonGroup);
            app.blueButton.Text = 'blue';
            app.LineStyleButtonGroup = uibuttongroup(app.DTab);
            app.LineStyleButtonGroup.Title = 'LineStyle';
            app.Button_2 = uiradiobutton(app.LineStyleButtonGroup);
            app.Button_2.Text = '實線';
            app.Button_2.Value = true;
            app.Button_3 = uiradiobutton(app.LineStyleButtonGroup);
            app.Button_3.Text = '虛線';
            app.Button_4 = uiradiobutton(app.LineStyleButtonGroup);
            app.Button_4.Text = '點線';
            app.usedefaultstyleCheckBox = uicheckbox(app.DTab);
            app.usedefaultstyleCheckBox.ValueChangedFcn = createCallbackFcn(app, @usedefaultstyleCheckBoxValueChanged, true);
            app.usedefaultstyleCheckBox.Text = 'use default style';
            app.DTab_2 = uitab(app.TabGroup);
            app.DTab_2.Title = '3D';
            app.ListListBox_2Label = uilabel(app.DTab_2);
            app.ListListBox_2Label.Text = 'List';
            app.ListListBox_2 = uilistbox(app.DTab_2);
            app.ListListBox_2.Items = {'1', '2', '3', '4'};
            app.ListListBox_2.ValueChangedFcn = createCallbackFcn(app, @ListListBox_2ValueChanged, true);
            app.ListListBox_2.Value = '1';
            app.ColormapButtonGroup = uibuttongroup(app.DTab_2);
            app.ColormapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColormapButtonGroupSelectionChanged, true);
            app.ColormapButtonGroup.Title = 'Color map';
            app.hotButton = uiradiobutton(app.ColormapButtonGroup);
            app.hotButton.Text = 'hot';
            app.hotButton.Value = true;
            app.coolButton = uiradiobutton(app.ColormapButtonGroup);
            app.coolButton.Text = 'cool';
            app.summerButton_2 = uiradiobutton(app.ColormapButtonGroup);
            app.summerButton_2.Text = 'summer';
            app.StartButton = uibutton(app.DrawingPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.CloseDrawingPanel = uiimage(app.DrawingPanel);
            app.CloseDrawingPanel.ImageClickedFcn = createCallbackFcn(app, @CloseDrawingPanelImageClicked, true);
            app.CloseDrawingPanel.ImageSource = fullfile(pathToMLAPP, 'close.png');
            app.AnimationPanel = uipanel(app.UIFigure);
            app.AnimationPanel.Title = 'Animation';
            app.UIAxesAnim = uiaxes(app.AnimationPanel);
            ylabel(app.UIAxesAnim, 'Y')
            zlabel(app.UIAxesAnim, 'Z')
            app.Label_3 = uilabel(app.AnimationPanel);
            app.Label_3.Text = '動畫列表';
            app.AnimDropDown = uidropdown(app.AnimationPanel);
            app.AnimDropDown.Items = {'Option 1', 'Option 2'};
            app.AnimExecuteBtn = uibutton(app.AnimationPanel, 'push');
            app.AnimExecuteBtn.ButtonPushedFcn = createCallbackFcn(app, @AnimExecuteBtnButtonPushed, true);
            app.AnimExecuteBtn.Text = '執行';
            app.AnimStopBtn = uibutton(app.AnimationPanel, 'push');
            app.AnimStopBtn.ButtonPushedFcn = createCallbackFcn(app, @AnimStopBtnPushed, true);
            app.AnimStopBtn.Text = '停止';
            app.Label_AnimState = uilabel(app.AnimationPanel);
            app.Label_AnimState.Text = '目前狀態 : ';
            app.CloseAnimPanel = uiimage(app.AnimationPanel);
            app.CloseAnimPanel.ImageClickedFcn = createCallbackFcn(app, @CloseAnimPanelImageClicked, true);
            app.CloseAnimPanel.ImageSource = fullfile(pathToMLAPP, 'close.png');
            app.SettingPanel = uipanel(app.UIFigure);
            app.SettingPanel.Title = '開始選單';
            app.Label_4 = uilabel(app.SettingPanel);
            app.Label_4.Text = '背景切換';
            app.ChangeBGDropDown = uidropdown(app.SettingPanel);
            app.ChangeBGDropDown.Items = {'默認', '風景', '純色', '極光'};
            app.ChangeBGDropDown.ValueChangedFcn = createCallbackFcn(app, @ChangeBGDropDownValueChanged, true);
            app.ChangeBGDropDown.Value = '默認';
            app.shutdown = uiimage(app.SettingPanel);
            app.shutdown.ImageClickedFcn = createCallbackFcn(app, @shutdownClicked, true);
            app.shutdown.ImageSource = fullfile(pathToMLAPP, 'shutdown.png');
            app.shutdownPanel = uipanel(app.UIFigure);
            app.Image2 = uiimage(app.shutdownPanel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'shutdown.gif');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Poject_class0511

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end